classdef ArduinoPIDControl < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        DurationsEditField            matlab.ui.control.NumericEditField
        DurationsEditFieldLabel       matlab.ui.control.Label
        BaudRateEditField             matlab.ui.control.NumericEditField
        BaudRateEditFieldLabel        matlab.ui.control.Label
        DigitalFilterWeightEditField  matlab.ui.control.NumericEditField
        DigitalFilterWeightEditFieldLabel  matlab.ui.control.Label
        StepSizeradEditField          matlab.ui.control.NumericEditField
        StepSizeradEditFieldLabel     matlab.ui.control.Label
        KiEditField                   matlab.ui.control.NumericEditField
        KiEditFieldLabel              matlab.ui.control.Label
        KdEditField                   matlab.ui.control.NumericEditField
        KdEditFieldLabel              matlab.ui.control.Label
        KpEditField                   matlab.ui.control.NumericEditField
        KpEditFieldLabel              matlab.ui.control.Label
        ProfStephenMascaro2021Label   matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        SaveDataButton                matlab.ui.control.Button
        FileNameEditField             matlab.ui.control.EditField
        FileNameEditFieldLabel        matlab.ui.control.Label
        COMPortEditField              matlab.ui.control.EditField
        COMPortEditFieldLabel         matlab.ui.control.Label
        RunStepButton                 matlab.ui.control.Button
        UIAxes4                       matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes3                       matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        data; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunStepButton
        function RunStepButtonPushed(app, event)
            cla(app.UIAxes)
            cla(app.UIAxes3)
            cla(app.UIAxes2)
            cla(app.UIAxes4)
            app.data = [];
            drawnow
            Kp = app.KpEditField.Value;
            Kd = app.KdEditField.Value;
            Ki = app.KiEditField.Value;
            StepSize = app.StepSizeradEditField.Value;
            FilterWeight = app.DigitalFilterWeightEditField.Value;
            COM = app.COMPortEditField.Value;
            BaudRate = app.BaudRateEditField.Value;
            duration = app.DurationsEditField.Value;
            
            s1 = serialport(COM,BaudRate);  % open serial port
            while s1.NumBytesAvailable<1  % wait for ready command from Arduino
            end
            readline(s1)
            
            % Put control parameters in a string
            STR = sprintf('%4.2f %4.2f %4.2f %4.2f %4.2f %4.2f \n',[Kp,Kd,Ki,StepSize,FilterWeight,duration]);
            writeline(s1,STR);   % Transmit string of control parameters to Arduino
            
            % Read data
            pause(duration)  % Give the Arduino time to complete the experiment
            i = 1;
            
            while s1.NumBytesAvailable > 0    
                % receive data from Arduino one row at a time
                app.data(i,:) = str2num(readline(s1));
                i = 1 + i;
            end
            
            t = app.data(:,1);
            y = app.data(:,2);
            derivativeError = app.data(:,3);
            integralError = app.data(:,4);
            V = app.data(:,5);
            y_des = app.data(:,6);
                       
            plot(app.UIAxes,t,y,'r.')
            hold(app.UIAxes,"on")
            plot(app.UIAxes,t,y_des,'--k')
            hold(app.UIAxes,"off")
            plot(app.UIAxes2,t,V,'b.')
            plot(app.UIAxes3,t,derivativeError,'b.')
            plot(app.UIAxes4,t,integralError,'b.')
            
            %close the port
            clear('s1')

        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
            filename = app.FileNameEditField.Value;
            time = app.data(:,1);
            position = app.data(:,2);
            motorvoltage = app.data(:,5);
            save(filename,'time', 'position', 'motorvoltage');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Motor Position')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Position (rad)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Derivative Error')
            xlabel(app.UIAxes3, 'Time (s)')
            ylabel(app.UIAxes3, 'Derivative Error (rad/s)')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Motor Voltage')
            xlabel(app.UIAxes2, 'Time (s)')
            ylabel(app.UIAxes2, 'Motor Voltage (V)')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Integral Error')
            xlabel(app.UIAxes4, 'Time(s)')
            ylabel(app.UIAxes4, 'Integral Error (rad*s)')
            zlabel(app.UIAxes4, 'Z')
            app.RunStepButton = uibutton(app.UIFigure, 'push');
            app.RunStepButton.ButtonPushedFcn = createCallbackFcn(app, @RunStepButtonPushed, true);
            app.RunStepButton.Text = 'Run Step';
            app.COMPortEditFieldLabel = uilabel(app.UIFigure);
            app.COMPortEditFieldLabel.Text = 'COM Port';
            app.COMPortEditField = uieditfield(app.UIFigure, 'text');
            app.COMPortEditField.Value = 'com35';
            app.FileNameEditFieldLabel = uilabel(app.UIFigure);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.UIFigure, 'text');
            app.FileNameEditField.Value = 'data';
            app.SaveDataButton = uibutton(app.UIFigure, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Text = 'Save Data';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'App for Performing Closed Loop Step Response of a DC Motor Using an Arduino';
            app.ProfStephenMascaro2021Label = uilabel(app.UIFigure);
            app.ProfStephenMascaro2021Label.Text = 'Prof. Stephen Mascaro, 2021';
            app.KpEditFieldLabel = uilabel(app.UIFigure);
            app.KpEditFieldLabel.Text = 'Kp';
            app.KpEditField = uieditfield(app.UIFigure, 'numeric');
            app.KpEditField.Value = 1;
            app.KdEditFieldLabel = uilabel(app.UIFigure);
            app.KdEditFieldLabel.Text = 'Kd';
            app.KdEditField = uieditfield(app.UIFigure, 'numeric');
            app.KiEditFieldLabel = uilabel(app.UIFigure);
            app.KiEditFieldLabel.Text = 'Ki';
            app.KiEditField = uieditfield(app.UIFigure, 'numeric');
            app.StepSizeradEditFieldLabel = uilabel(app.UIFigure);
            app.StepSizeradEditFieldLabel.Text = 'Step Size (rad)';
            app.StepSizeradEditField = uieditfield(app.UIFigure, 'numeric');
            app.StepSizeradEditField.Value = 1;
            app.DigitalFilterWeightEditFieldLabel = uilabel(app.UIFigure);
            app.DigitalFilterWeightEditFieldLabel.Text = 'Digital Filter Weight';
            app.DigitalFilterWeightEditField = uieditfield(app.UIFigure, 'numeric');
            app.DigitalFilterWeightEditField.Value = 1;
            app.BaudRateEditFieldLabel = uilabel(app.UIFigure);
            app.BaudRateEditFieldLabel.Text = 'Baud Rate';
            app.BaudRateEditField = uieditfield(app.UIFigure, 'numeric');
            app.BaudRateEditField.ValueDisplayFormat = '%.0f';
            app.BaudRateEditField.Value = 250000;
            app.DurationsEditFieldLabel = uilabel(app.UIFigure);
            app.DurationsEditFieldLabel.Text = 'Duration (s)';
            app.DurationsEditField = uieditfield(app.UIFigure, 'numeric');
            app.DurationsEditField.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ArduinoPIDControl

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end