classdef HyrumColeman_Lab3_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        PhaseShiftDegLabel            matlab.ui.control.Label
        PhaseShiftEditField           matlab.ui.control.NumericEditField
        DominantFrequencyHzLabel      matlab.ui.control.Label
        CH1FreqField                  matlab.ui.control.NumericEditField
        CH0FreqField                  matlab.ui.control.NumericEditField
        MotorVoltageEditField         matlab.ui.control.NumericEditField
        MotorVoltageEditFieldLabel    matlab.ui.control.Label
        MotorVoltageSlider            matlab.ui.control.Slider
        MotorSwitch                   matlab.ui.control.ToggleSwitch
        MotorSwitchLabel              matlab.ui.control.Label
        FileNameEditField             matlab.ui.control.EditField
        FileNameEditFieldLabel        matlab.ui.control.Label
        CH1Label                      matlab.ui.control.Label
        CH0Label                      matlab.ui.control.Label
        AverageVoltageVLabel          matlab.ui.control.Label
        PeaptoPeakAmplitudeVLabel     matlab.ui.control.Label
        CH1AveField                   matlab.ui.control.NumericEditField
        CH0AveField                   matlab.ui.control.NumericEditField
        CH1AmpField                   matlab.ui.control.NumericEditField
        CH0AmpField                   matlab.ui.control.NumericEditField
        SaveButton                    matlab.ui.control.Button
        DurationEditField             matlab.ui.control.NumericEditField
        DurationsLabel                matlab.ui.control.Label
        RateEditField                 matlab.ui.control.NumericEditField
        SamplingRateHzEditFieldLabel  matlab.ui.control.Label
        RunButton                     matlab.ui.control.StateButton
        FFTAxes                       matlab.ui.control.UIAxes
        OscopeAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Ain % Analog Input Session
        Dout % Digital Output Session
        CH0 % Analog input channel 0
        CH1 % Analog input channel 1
        Time % Array of time samples
        Data % Array of data samples
        Aout % Analog output session
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            DevNum = daqlist;
            DevNum = table2array(DevNum(1,2));
            
            app.Ain = daq("ni");
            app.Dout = daq("ni");
            app.Aout = daq("ni");
            addoutput(app.Dout, DevNum, "port0/line0", "Digital");
            addoutput(app.Aout, DevNum, "ao0", "Voltage");
            app.CH0 = addinput(app.Ain, DevNum, "ai0", "Voltage");
            app.CH1 = addinput(app.Ain, DevNum, "ai1", "Voltage");
            app.CH0.TerminalConfig = "SingleEnded";
            app.CH1.TerminalConfig = "SingleEnded";
        end

        % Value changed function: RunButton
        function RunButtonValueChanged(app, event)
            if (app.RunButton.Text == "Run Oscilloscope")
                app.RunButton.Text = "Stop Oscilloscope";
            else
                app.RunButton.Text = "Run Oscilloscope";
            end

            while(app.RunButton.Value)


                rate = app.RateEditField.Value;
                duration = app.DurationEditField.Value;

                app.Ain.Rate = rate;
                result = read(app.Ain, seconds(duration));
                app.Time = result.Time;
                app.Data = result.Variables;
                
                plot(app.OscopeAxes, app.Time, app.Data);
                legend(app.OscopeAxes, "CH0", "CH1");
                drawnow;

                min0 = min(app.Data(:,1));
                max0 = max(app.Data(:,1));
                ave0 = mean(app.Data(:,1));
                min1 = min(app.Data(:,2));
                max1 = max(app.Data(:,2));
                ave1 = mean(app.Data(:,2));

                app.CH0AmpField.Value = max0-min0;
                app.CH1AmpField.Value = max1-min1;
                app.CH0AveField.Value = ave0;
                app.CH1AveField.Value = ave1;


                % remove DC offset and compute FFT
                [Mag0, phase0, freq0] = fft_sample(app.Data(:, 1)-ave0, rate);
                [Mag1, phase1, freq1] = fft_sample(app.Data(:, 2)-ave1, rate);
                plot(app.FFTAxes, freq0, Mag0, freq1, Mag1);
                drawnow; % this makes sure the graphics update

                % compute and display dominant frequencies
                [~, index0] = max(Mag0);
                [~, index1] = max(Mag1);
                app.CH0FreqField.Value = freq0(index0);
                app.CH1FreqField.Value = freq1(index1);

                % compute and display phase shift between CH0 and CH1
                phase_shift = phase1(index1) - phase0(index0);
                if phase_shift > 180
                    phase_shift = phase_shift - 360;
                elseif phase_shift < -180
                    phase_shift = phase_shift + 360;
                end
                app.PhaseShiftEditField.Value = phase_shift;

            end


        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            filename = app.FileNameEditField.Value;
            rawData = app.Data;
            timeData = app.Time;
            save(filename, 'rawData', 'timeData');
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if(app.RunButton.Value == 0)
                delete(app)
            else
                errordlg("Please stop data aquisition before closing the app", fontname="COMIC SANS MS");
            end
        end

        % Value changed function: MotorSwitch
        function MotorSwitchValueChanged(app, event)
            app.MotorVoltageSlider.Value = 0;
            app.MotorVoltageEditField.Value = 0;
            ArduinoVolts = 2.5;
            value = app.MotorSwitch.Value;
            if(strcmp(value, "Off"))
                write(app.Dout, 0);
            else
                write(app.Dout, 1);
            end
            write(app.Aout, ArduinoVolts);
        end

        % Value changing function: MotorVoltageSlider
        function MotorVoltageSliderValueChanging(app, event)
            MotorVolts = event.Value;
            app.MotorVoltageEditField.Value = MotorVolts;

            ArduinoVolts = (MotorVolts + 5) / 2;
            write(app.Aout, ArduinoVolts);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.OscopeAxes = uiaxes(app.UIFigure);
            title(app.OscopeAxes, 'Oscilloscope')
            xlabel(app.OscopeAxes, 'Time (s)')
            ylabel(app.OscopeAxes, 'Voltage (V)')
            zlabel(app.OscopeAxes, 'Z')
            app.FFTAxes = uiaxes(app.UIFigure);
            title(app.FFTAxes, 'Spectral Analysis')
            xlabel(app.FFTAxes, 'Frequency (Hz)')
            ylabel(app.FFTAxes, 'Magnitude (V)')
            zlabel(app.FFTAxes, 'Z')
            app.RunButton = uibutton(app.UIFigure, 'state');
            app.RunButton.ValueChangedFcn = createCallbackFcn(app, @RunButtonValueChanged, true);
            app.RunButton.Text = 'Run Oscilloscope';
            app.SamplingRateHzEditFieldLabel = uilabel(app.UIFigure);
            app.SamplingRateHzEditFieldLabel.Text = 'Sampling Rate (Hz)';
            app.RateEditField = uieditfield(app.UIFigure, 'numeric');
            app.RateEditField.Value = 1000;
            app.DurationsLabel = uilabel(app.UIFigure);
            app.DurationsLabel.Text = 'Duration (s)';
            app.DurationEditField = uieditfield(app.UIFigure, 'numeric');
            app.DurationEditField.Value = 1;
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save Data';
            app.CH0AmpField = uieditfield(app.UIFigure, 'numeric');
            app.CH0AmpField.Editable = 'off';
            app.CH1AmpField = uieditfield(app.UIFigure, 'numeric');
            app.CH1AmpField.Editable = 'off';
            app.CH0AveField = uieditfield(app.UIFigure, 'numeric');
            app.CH0AveField.Editable = 'off';
            app.CH1AveField = uieditfield(app.UIFigure, 'numeric');
            app.CH1AveField.Editable = 'off';
            app.PeaptoPeakAmplitudeVLabel = uilabel(app.UIFigure);
            app.PeaptoPeakAmplitudeVLabel.Text = 'Peap-to-Peak Amplitude (V)';
            app.AverageVoltageVLabel = uilabel(app.UIFigure);
            app.AverageVoltageVLabel.Text = 'Average Voltage (V)';
            app.CH0Label = uilabel(app.UIFigure);
            app.CH0Label.Text = 'CH0';
            app.CH1Label = uilabel(app.UIFigure);
            app.CH1Label.Text = 'CH1';
            app.FileNameEditFieldLabel = uilabel(app.UIFigure);
            app.FileNameEditFieldLabel.Text = 'File Name:';
            app.FileNameEditField = uieditfield(app.UIFigure, 'text');
            app.MotorSwitchLabel = uilabel(app.UIFigure);
            app.MotorSwitchLabel.Text = 'Motor';
            app.MotorSwitch = uiswitch(app.UIFigure, 'toggle');
            app.MotorSwitch.ValueChangedFcn = createCallbackFcn(app, @MotorSwitchValueChanged, true);
            app.MotorVoltageSlider = uislider(app.UIFigure);
            app.MotorVoltageSlider.Limits = [-5 5];
            app.MotorVoltageSlider.ValueChangingFcn = createCallbackFcn(app, @MotorVoltageSliderValueChanging, true);
            app.MotorVoltageEditFieldLabel = uilabel(app.UIFigure);
            app.MotorVoltageEditFieldLabel.Text = 'Motor Voltage';
            app.MotorVoltageEditField = uieditfield(app.UIFigure, 'numeric');
            app.MotorVoltageEditField.Editable = 'off';
            app.CH0FreqField = uieditfield(app.UIFigure, 'numeric');
            app.CH0FreqField.Editable = 'off';
            app.CH1FreqField = uieditfield(app.UIFigure, 'numeric');
            app.CH1FreqField.Editable = 'off';
            app.DominantFrequencyHzLabel = uilabel(app.UIFigure);
            app.DominantFrequencyHzLabel.Text = 'Dominant Frequency (Hz)';
            app.PhaseShiftEditField = uieditfield(app.UIFigure, 'numeric');
            app.PhaseShiftEditField.Editable = 'off';
            app.PhaseShiftDegLabel = uilabel(app.UIFigure);
            app.PhaseShiftDegLabel.Text = 'Phase Shift (Deg)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HyrumColeman_Lab3_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end