classdef Lab7_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Slider                         matlab.ui.control.Slider
        FilterEditField                matlab.ui.control.NumericEditField
        IIRFilterWeightalphaLabel      matlab.ui.control.Label
        PhaseShiftEditField            matlab.ui.control.NumericEditField
        PhaseShiftdegEditFieldLabel    matlab.ui.control.Label
        DetectedSignalPropertiesLabel  matlab.ui.control.Label
        DominantFrequencyHzLabel       matlab.ui.control.Label
        CH1FreqField                   matlab.ui.control.NumericEditField
        CH0FreqField                   matlab.ui.control.NumericEditField
        CH0filteredLabel               matlab.ui.control.Label
        CH0unfilteredLabel             matlab.ui.control.Label
        AverageVoltageVLabel           matlab.ui.control.Label
        PeaktoPeakAmplitudeVLabel      matlab.ui.control.Label
        CH1AveField                    matlab.ui.control.NumericEditField
        CH0AveField                    matlab.ui.control.NumericEditField
        CH0AmpField                    matlab.ui.control.NumericEditField
        CH1AmpField                    matlab.ui.control.NumericEditField
        FileNameEditField              matlab.ui.control.EditField
        FileNameEditFieldLabel         matlab.ui.control.Label
        SaveButton                     matlab.ui.control.Button
        DurationEditField              matlab.ui.control.NumericEditField
        NumberofSamplestosampleLabel   matlab.ui.control.Label
        RateEditField                  matlab.ui.control.NumericEditField
        SamplingRateHzEditFieldLabel   matlab.ui.control.Label
        RunButton                      matlab.ui.control.StateButton
        FFT_Axes                       matlab.ui.control.UIAxes
        OscopeAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Ain % Analog Input Session
        CH0 % Analog Input Channel 0
        Time % array of time samples
        Data % array of data samples
        Dout % Digital Output Session
        Aout % Analog Output Session
    end
    
    methods (Access = private)
        
        function [Mag, phase, freq] = fft_sample(app, data, sample_rate)
            G = fft(data);
            spectralResolution = sample_rate/length(data);
            f_Nyquist = sample_rate/2;
            freq = 0:spectralResolution:f_Nyquist;
            Mag = abs(G(1:length(freq)))/length(data);
            Mag(2:end-1) = 2*Mag(2:end-1);
            phase = angle(G)*180/pi;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Ain = daq("ni");
            app.CH0 = addinput(app.Ain,"Dev2","ai0","Voltage");
            app.CH0.TerminalConfig = "SingleEnded";

        end

        % Value changed function: RunButton
        function RunButtonValueChanged(app, event)
            while(app.RunButton.Value) % loops while button is pressed
                rate = app.RateEditField.Value;
                duration = app.DurationEditField.Value;

                % These next lines are for the pre-lab only. 
                % Comment them out after you are in lab and replace with
                % commands to real DAQ hardware
                % time = 0:1/rate:numSamples/rate;
                % data = sin(10*time);
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                %Acquire real data from DAQ hardware    
                app.Ain.Rate = rate;

                result = read(app.Ain,seconds(duration));
                app.Time = result.Time;
                app.Data = result.Variables;

                % app.Ain.DurationInSeconds = numSamples/rate;
                % [app.Data,app.Time] = app.Ain.startForeground;
                            
                % apply IIR filtering
                alpha = app.FilterEditField.Value;
                filtered_Data = IIR_WA(app.Data,alpha);
                app.Data = [app.Data filtered_Data];
                plot(app.OscopeAxes,app.Time,app.Data);
                legend(app.OscopeAxes,'CH0 (unfiltered)','CH0 (filtered)')
                

                min0 = min(app.Data(:,1));
                max0 = max(app.Data(:,1));
                ave0 = mean(app.Data(:,1));
                min1 = min(app.Data(:,2));
                max1 = max(app.Data(:,2));
                ave1 = mean(app.Data(:,2));
                app.CH0AmpField.Value = max0-min0;
                app.CH1AmpField.Value = max1-min1;
                app.CH0AveField.Value = ave0;
                app.CH1AveField.Value = ave1;
                
                % remove DC offset and compute FFT
                [Mag0,phase0,freq]=fft_sample(app,app.Data(:,1)-ave0,rate);
                [Mag1,phase1,freq]=fft_sample(app,app.Data(:,2)-ave1,rate);
                plot(app.FFT_Axes,freq,Mag0,freq,Mag1);
                drawnow; % this makes sure the graphics update
                
                % compute and display dominant frequencies
                [~,index0]=max(Mag0);
                [~,index1]=max(Mag1);
                app.CH0FreqField.Value = freq(index0);
                app.CH1FreqField.Value = freq(index1);

                % compute and display phase shift between CH0 and CH1
                phase_shift = phase1(index1)-phase0(index0);
                if phase_shift > 180
                    phase_shift = phase_shift - 360;
                elseif phase_shift < -180
                    phase_shift = phase_shift + 360;
                end
                app.PhaseShiftEditField.Value = phase_shift;
                
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            filename = app.FileNameEditField.Value;
            rawdata = [app.Time,app.Data];
            save(filename,'rawdata');
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if(app.RunButton.Value == 0)
                delete(app)
            else
                errordlg('Please stop data aquisition before closing the app');
            end 
            
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            app.FilterEditField.Value = app.Slider.Value;
        end

        % Value changed function: FilterEditField
        function FilterEditFieldValueChanged(app, event)
            app.Slider.Value = app.FilterEditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.OscopeAxes = uiaxes(app.UIFigure);
            title(app.OscopeAxes, 'Oscilloscope')
            xlabel(app.OscopeAxes, 'Time (s)')
            ylabel(app.OscopeAxes, 'Voltage (V)')
            zlabel(app.OscopeAxes, 'Z')
            app.FFT_Axes = uiaxes(app.UIFigure);
            title(app.FFT_Axes, 'Spectral Analysis')
            xlabel(app.FFT_Axes, 'Frequency (Hz)')
            ylabel(app.FFT_Axes, 'Magnitude (V)')
            zlabel(app.FFT_Axes, 'Z')
            app.RunButton = uibutton(app.UIFigure, 'state');
            app.RunButton.ValueChangedFcn = createCallbackFcn(app, @RunButtonValueChanged, true);
            app.RunButton.Text = 'Run Oscope';
            app.SamplingRateHzEditFieldLabel = uilabel(app.UIFigure);
            app.SamplingRateHzEditFieldLabel.Text = 'Sampling Rate (Hz)';
            app.RateEditField = uieditfield(app.UIFigure, 'numeric');
            app.RateEditField.Value = 1000;
            app.NumberofSamplestosampleLabel = uilabel(app.UIFigure);
            app.NumberofSamplestosampleLabel.Text = 'Duration (sec)';
            app.DurationEditField = uieditfield(app.UIFigure, 'numeric');
            app.DurationEditField.Value = 1;
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save Data';
            app.FileNameEditFieldLabel = uilabel(app.UIFigure);
            app.FileNameEditFieldLabel.Text = 'File Name:';
            app.FileNameEditField = uieditfield(app.UIFigure, 'text');
            app.CH1AmpField = uieditfield(app.UIFigure, 'numeric');
            app.CH1AmpField.Editable = 'off';
            app.CH0AmpField = uieditfield(app.UIFigure, 'numeric');
            app.CH0AmpField.Editable = 'off';
            app.CH0AveField = uieditfield(app.UIFigure, 'numeric');
            app.CH0AveField.Editable = 'off';
            app.CH1AveField = uieditfield(app.UIFigure, 'numeric');
            app.CH1AveField.Editable = 'off';
            app.PeaktoPeakAmplitudeVLabel = uilabel(app.UIFigure);
            app.PeaktoPeakAmplitudeVLabel.Text = 'Peak-to-Peak Amplitude (V)';
            app.AverageVoltageVLabel = uilabel(app.UIFigure);
            app.AverageVoltageVLabel.Text = 'Average Voltage (V)';
            app.CH0unfilteredLabel = uilabel(app.UIFigure);
            app.CH0unfilteredLabel.Text = 'CH0 (unfiltered)';
            app.CH0filteredLabel = uilabel(app.UIFigure);
            app.CH0filteredLabel.Text = 'CH0 (filtered)';
            app.CH0FreqField = uieditfield(app.UIFigure, 'numeric');
            app.CH0FreqField.Editable = 'off';
            app.CH1FreqField = uieditfield(app.UIFigure, 'numeric');
            app.CH1FreqField.Editable = 'off';
            app.DominantFrequencyHzLabel = uilabel(app.UIFigure);
            app.DominantFrequencyHzLabel.Text = 'Dominant Frequency (Hz)';
            app.DetectedSignalPropertiesLabel = uilabel(app.UIFigure);
            app.DetectedSignalPropertiesLabel.Text = 'Detected Signal Properties';
            app.PhaseShiftdegEditFieldLabel = uilabel(app.UIFigure);
            app.PhaseShiftdegEditFieldLabel.Text = 'Phase Shift (deg)';
            app.PhaseShiftEditField = uieditfield(app.UIFigure, 'numeric');
            app.IIRFilterWeightalphaLabel = uilabel(app.UIFigure);
            app.IIRFilterWeightalphaLabel.Text = 'IIR Filter Weight (alpha)';
            app.FilterEditField = uieditfield(app.UIFigure, 'numeric');
            app.FilterEditField.ValueChangedFcn = createCallbackFcn(app, @FilterEditFieldValueChanged, true);
            app.FilterEditField.Value = 1;
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 1];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Lab7_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end