classdef Kinblandford_Lab2_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        MotorVoltageSlider          matlab.ui.control.Slider
        MotorVoltageEditField       matlab.ui.control.NumericEditField
        MotorVoltageEditFieldLabel  matlab.ui.control.Label
        MotorSwitch                 matlab.ui.control.ToggleSwitch
        MotorSwitchLabel            matlab.ui.control.Label
        CH1Label                    matlab.ui.control.Label
        CH0Label                    matlab.ui.control.Label
        AverageVoltageVLabel        matlab.ui.control.Label
        PeakToPeakVLabel            matlab.ui.control.Label
        CH0AmpField                 matlab.ui.control.NumericEditField
        CH0AveField                 matlab.ui.control.NumericEditField
        CH1AveField                 matlab.ui.control.NumericEditField
        CH1AmpField                 matlab.ui.control.NumericEditField
        FileNameEditField           matlab.ui.control.EditField
        FileNameEditFieldLabel      matlab.ui.control.Label
        SaveButton                  matlab.ui.control.Button
        DurationEditField           matlab.ui.control.NumericEditField
        DurationsLabel              matlab.ui.control.Label
        RateEditField               matlab.ui.control.NumericEditField
        SamplingRateHzLabel         matlab.ui.control.Label
        RunButton                   matlab.ui.control.StateButton
        OscilloscopeAxes            matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Ain % Analog Input Session
        Dout % Digital Output Session
        CH0 % Analog Input Channel 0
        CH1 % Analog Input Channel 1
        Time % Array of Time Samples
        Data % Array of Data Samples
        Aout % Analog Output Session
        port = "Dev7";
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Ain = daq("ni");
            app.CH0 = addinput(app.Ain, app.port, "ai0", "Voltage");
            app.CH1 = addinput(app.Ain, app.port, "ai1", "Voltage");
            app.CH0.TerminalConfig = "SingleEnded";
            app.CH1.TerminalConfig = "SingleEnded";

            app.Dout = daq("ni");
            addoutput(app.Dout, app.port, "port0/line0", "Digital");

            app.Aout = daq("ni");
            addoutput(app.Aout, app.port, "ao0", "Voltage");
        end

        % Value changed function: RunButton
        function RunButtonValueChanged(app, event)
            while (app.RunButton.Value)
                rate = app.RateEditField.Value;
                duration = app.DurationEditField.Value;
                % PRE LAB ONLY:::
                %time = 0:1/rate:duration;
                %data =  sin(10*time);
                % :::

                app.Ain.Rate = rate;
                result = read(app.Ain, seconds(duration));
                app.Time = result.Time;
                app.Data = result.Variables;

                plot(app.OscilloscopeAxes, app.Time, app.Data);
                legend(app.OscilloscopeAxes, 'CH0', 'CH1')
                drawnow;

                min0 = min(app.Data(:,1));
                max0 = max(app.Data(:,1));
                ave0 = mean(app.Data(:,1));
                min1 = min(app.Data(:,2));
                max1 = max(app.Data(:,2));
                ave1 = mean(app.Data(:,2));
                app.CH0AmpField.Value = max0-min0;
                app.CH1AmpField.Value = max1-min1;
                app.CH0AveField.Value = ave0;
                app.CH1AveField.Value = ave1;
            end
        end

        % Button pushed function: SaveButton
        function saveButtonPushed(app, event)
            filename = app.FileNameEditField.Value;
            rawData = app.Data;
            timeData = app.Time;
            save(filename, 'rawData', 'timeData');
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if (app.RunButton.Value == 0)
                delete(app)
            else
                errordlg('Stop DAQ Before Closing App')
            end 
        end

        % Value changed function: MotorSwitch
        function MotorSwitchValueChanged(app, event)
            % value = app.MotorSwitch.Value;
            % if(strcmp(value,'Off'))
            %     write(app.Dout,0);
            % else
            %     write(app.Dout,1);
            % end
            % Reset the Motor Voltage command to 0
            app.MotorVoltageSlider.Value = 0;
            app.MotorVoltageEditField.Value = 0;
            % Motor voltage of 0 maps to Arduino voltage of 2.5
            ArduinoVolts = 2.5;
            % Update digital and Analog outputs
            if(strcmp(app.MotorSwitch.Value, 'Off'))
                write(app.Dout,0);
            else
                write(app.Dout,1);
            end
            write(app.Aout,ArduinoVolts);
        end

        % Value changing function: MotorVoltageSlider
        function MotorVoltageSliderValueChanging(app, event)
            MotorVolts = event.Value;
            app.MotorVoltageEditField.Value = MotorVolts;

            ArduinoVolts = (MotorVolts+5)/2;
            write(app.Aout,ArduinoVolts);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.OscilloscopeAxes = uiaxes(app.UIFigure);
            title(app.OscilloscopeAxes, 'Oscilloscope')
            xlabel(app.OscilloscopeAxes, 'Time (s)')
            ylabel(app.OscilloscopeAxes, 'Voltage (V)')
            zlabel(app.OscilloscopeAxes, 'Z')
            app.RunButton = uibutton(app.UIFigure, 'state');
            app.RunButton.ValueChangedFcn = createCallbackFcn(app, @RunButtonValueChanged, true);
            app.RunButton.Text = 'Run Oscope';
            app.SamplingRateHzLabel = uilabel(app.UIFigure);
            app.SamplingRateHzLabel.Text = 'Sampling Rate (Hz)';
            app.RateEditField = uieditfield(app.UIFigure, 'numeric');
            app.RateEditField.Value = 1000;
            app.DurationsLabel = uilabel(app.UIFigure);
            app.DurationsLabel.Text = 'Duration (s)';
            app.DurationEditField = uieditfield(app.UIFigure, 'numeric');
            app.DurationEditField.Value = 1;
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.SaveButton.Text = 'Save Data';
            app.FileNameEditFieldLabel = uilabel(app.UIFigure);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.UIFigure, 'text');
            app.CH1AmpField = uieditfield(app.UIFigure, 'numeric');
            app.CH1AmpField.Editable = 'off';
            app.CH1AveField = uieditfield(app.UIFigure, 'numeric');
            app.CH1AveField.Editable = 'off';
            app.CH0AveField = uieditfield(app.UIFigure, 'numeric');
            app.CH0AveField.Editable = 'off';
            app.CH0AmpField = uieditfield(app.UIFigure, 'numeric');
            app.CH0AmpField.Editable = 'off';
            app.PeakToPeakVLabel = uilabel(app.UIFigure);
            app.PeakToPeakVLabel.Text = 'Peak-To-Peak (V)';
            app.AverageVoltageVLabel = uilabel(app.UIFigure);
            app.AverageVoltageVLabel.Text = 'Average Voltage (V)';
            app.CH0Label = uilabel(app.UIFigure);
            app.CH0Label.Text = 'CH0';
            app.CH1Label = uilabel(app.UIFigure);
            app.CH1Label.Text = 'CH1';
            app.MotorSwitchLabel = uilabel(app.UIFigure);
            app.MotorSwitchLabel.Text = 'Motor';
            app.MotorSwitch = uiswitch(app.UIFigure, 'toggle');
            app.MotorSwitch.ValueChangedFcn = createCallbackFcn(app, @MotorSwitchValueChanged, true);
            app.MotorVoltageEditFieldLabel = uilabel(app.UIFigure);
            app.MotorVoltageEditFieldLabel.Text = 'Motor Voltage';
            app.MotorVoltageEditField = uieditfield(app.UIFigure, 'numeric');
            app.MotorVoltageSlider = uislider(app.UIFigure);
            app.MotorVoltageSlider.Limits = [-5 5];
            app.MotorVoltageSlider.ValueChangingFcn = createCallbackFcn(app, @MotorVoltageSliderValueChanging, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Kinblandford_Lab2_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end